classdef Pipe_Make_up_Simulator_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure              % Drilling Simulator
        Panel              matlab.ui.container.Panel     % Input Parameters
        LabelDropDown      matlab.ui.control.Label       % Crew Selection
        Crew_select        matlab.ui.control.DropDown    % Crew 1, Crew 2, ...
        LabelDropDown2     matlab.ui.control.Label       % Connection Process
        Connect_process    matlab.ui.control.DropDown    % Iron Roughneck, ...
        LabelDropDown3     matlab.ui.control.Label       % Dope Selection
        Dope_select        matlab.ui.control.DropDown    % FF = 0.8, FF = 0...
        Press_start        matlab.ui.control.StateButton % Start
        PauseResume        matlab.ui.control.Button      % Pause
        LabelTextArea      matlab.ui.control.Label       % Make Up Torque (...
        MUTorque           matlab.ui.control.TextArea   
        Label2             matlab.ui.control.Label       % Make Up Torque (...
        MUTorqueS          matlab.ui.control.TextArea   
        Panel2             matlab.ui.container.Panel     % Simulation
        MUTorque_Specified matlab.ui.container.Panel    
        Panel4             matlab.ui.container.Panel    
        Panel5             matlab.ui.container.Panel    
        pipe_box           matlab.ui.container.Panel    
        pipe               matlab.ui.container.Panel    
        pipe_pin           matlab.ui.container.Panel    
        Topdrive_top       matlab.ui.container.Panel     % Top Drive
        bottom_pipe        matlab.ui.container.Panel    
        bottom_pipe_box    matlab.ui.container.Panel    
        Topdrive_bottom    matlab.ui.container.Panel     % Top Drive
        left_slip          matlab.ui.container.Panel    
        right_slip         matlab.ui.container.Panel    
        Panel17            matlab.ui.container.Panel    
        Panel18            matlab.ui.container.Panel    
        Panel19            matlab.ui.container.Panel    
        Panel20            matlab.ui.container.Panel    
        Panel21            matlab.ui.container.Panel    
        Panel22            matlab.ui.container.Panel    
        pipe1_box          matlab.ui.container.Panel    
        pipe1              matlab.ui.container.Panel    
        pipe1_pin          matlab.ui.container.Panel    
        LabelGauge         matlab.ui.control.Label       % Make Up Torque (Nm)
        Gauge              matlab.ui.control.Gauge       % [0 20000]
        tong_right         matlab.ui.container.Panel     % Lead Tong
        tong_left          matlab.ui.container.Panel     % Backup Tong
        LabelLinearGauge   matlab.ui.control.Label       % Risk-meter
        riskometer         matlab.ui.control.LinearGauge % [0 1]
        Label              matlab.ui.control.Label       % Derrick Floor
        MUGauge            matlab.ui.control.LinearGauge % [0 1]
        Panel26            matlab.ui.container.Panel     % Output Display
        UIAxes             matlab.ui.control.UIAxes      % Torque-Turn Plot
        Log                matlab.ui.control.TextArea   
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tong_left.Visible = 'off';
            app.tong_right.Visible = 'off';
            app.pipe_box.Visible = 'off';
            app.pipe.Visible = 'off';
            app.pipe_pin.Visible = 'off';
            app.Topdrive_top.Visible = 'off';
            app.PauseResume.Visible = 'off';
            app.left_slip.Visible='off';
            app.right_slip.Visible='off';
            global log_msg;
            log_msg = sprintf('Drill string held at bottom using Top drive...\n\n');
            app.Log.Value = log_msg;
            %             global TIME;
            %             TIME = 0;
            %             global T1;
            %             T1 = clock;
        end

        % Press_start value changed function
        function Press_Start(app)
            %             global TIME;
            global log_msg;
            log_msg = sprintf('Drill string held at bottom using Top drive...\n\n');
            app.Log.Value = log_msg;
            app.MUGauge.Value = 0;
            plot(app.UIAxes,0,0);
            app.pipe1.Visible = 'on';
            app.pipe1.BackgroundColor = 'w';
            app.pipe1_box.Visible = 'on';
            app.pipe1_box.BackgroundColor = 'w';
            app.pipe1_pin.Visible = 'on';
            app.pipe1_pin.BackgroundColor = 'w';
            
            
            filename = 'Input.xlsx';
            [a,vis] = xlsread(filename,2);
            vis = char(vis);
            [a,col] = xlsread(filename,3);
            col = char(col);
            crew = xlsread(filename,4);
            conn = xlsread(filename,5);
            torq = xlsread(filename,6);
            safety = xlsread(filename,7);
            ACT=1;
            app.Log.Value = '';
            
            %% Crew data setup
            crew_sltd = app.Crew_select.Value;
            crew_no_sltd = str2double(crew_sltd(6));
            
            for i = 1:6
                if(crew_no_sltd==i)
                    crew_time = crew(:,i);
                end
            end
            
            app.Crew_select.Enable = 'off';
            
            %% Connection Type setup
            conn_sltd = app.Connect_process.Value;
            if strcmp(conn_sltd,'Iron Roughneck')
                crew_time(9,1) = conn(1,crew_no_sltd);
            elseif strcmp(conn_sltd,'Power Tongs')
                crew_time(9,1) = conn(2,crew_no_sltd);
            elseif strcmp(conn_sltd,'Pipe Spinners')
                crew_time(9,1) = conn(3,crew_no_sltd);
            elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                crew_time(9,1) = conn(4,crew_no_sltd);
            end
            
            crew_acc = crew_time;
            
            for i = 2:11
                crew_acc(i) = crew_acc(i-1)+crew_time(i);
            end
            
            app.Connect_process.Enable = 'off';
            
            %% Dope type used
            dope_sltd = app.Dope_select.Value;
            app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
            if strcmp(dope_sltd,'FF = 0.8')
                app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
            elseif strcmp(dope_sltd,'FF = 0.9')
                app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
            elseif strcmp(dope_sltd,'FF = 1.0')
                app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
            elseif strcmp(dope_sltd,'FF = 1.1')
                app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
            elseif strcmp(dope_sltd,'FF = 1.2')
                app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
            end
            
            app.Dope_select.Enable = 'off';
            
            
            %% Start Press
            
            app.Press_start.Value = 0;
            app.Press_start.Visible = 'off';
            app.PauseResume.Visible = 'on';
            tic;
            
            %             str = formatTimeFcn(TIME);
            %             app.timer_display.Value = str;
            %
            %             adapter = @(timerobject, eventdata) timerFcn(app);
            %             htimer = timer('TimerFcn',adapter,'Period',0.001,'ExecutionMode','FixedRate');
            %             start(htimer);
            
            if ACT == 1 && strcmp(app.PauseResume.Text,'Pause')
                
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sSlips in... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.riskometer.Value = safety(ACT);
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
                
            elseif ACT == 1 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%s Slips in... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.riskometer.Value = safety(ACT);
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 2 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sElevator is disengaged... %0.3f sec\nNow raising top drive to latch stand...\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 2 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sElevator is disengaged... %0.3f sec\nNow raising top drive to latch stand...\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 3 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sTop Drive reaches top position and stops... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 3 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sTop Drive reaches top position and stops... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 4 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sDerrickman latches stand and engages elevator... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 4 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sDerrickman latches stand and engages elevator... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 5 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sTop drive picks up stand... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 5 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sTop Drive picks up stand... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 6 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sFloor men use ropes to stabilise moving stand... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 6 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sFloor men use ropes to stabilise moving stand... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 7 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sDope is applied on the stand pin connection... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 7 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sDope is applied on the stand pin connection... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 8 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sFloor crew align the stand pin over rest of pipe box for connection... %0.3f sec\nNow the connection can be made with the chosen tools\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 8 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sFloor crew align the stand pin over rest of pipe box for connection... %0.3f sec\n Now the connection can be made with the chosen tools\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 9 && strcmp(app.PauseResume.Text,'Pause')
                if strcmp(app.Connect_process.Value,'Iron Roughneck')
                    pause(15);
                    log_msg = sprintf('%sTongs align and Grip Pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.2;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(8);
                    log_msg = sprintf('%sSpinners screw pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.2;
                    app.Log.Value = log_msg;
                    pause(5);
                elseif strcmp(app.Connect_process.Value,'Power Tongs')
                    pause(10);
                    log_msg = sprintf('%sAlign Power tongs to pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.4;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(10);
                    log_msg = sprintf('%sProvide make up torque... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.4;
                    app.Log.Value = log_msg;
                elseif strcmp(app.Connect_process.Value,'Pipe Spinners')
                    pause(8);
                    log_msg = sprintf('%sSpinner aligns and screws pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.6;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(8);
                    log_msg = sprintf('%sTongs are aligned and final make up torque is achieved... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.6;
                    app.Log.Value = log_msg;
                elseif strcmp(app.Connect_process.Value,'Spinning Chains and Tongs')
                    pause(5);
                    log_msg = sprintf('%sUse Spinners to screw connection... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.75;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(5);
                    log_msg = sprintf('%sUse tongs for final make up torque... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.75;
                    app.Log.Value = log_msg;
                end
                
                if strcmp(dope_sltd,'FF = 0.8')
                    i=1;
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,2);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,2));
                elseif strcmp(dope_sltd,'FF = 0.9')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,3);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,3));
                elseif strcmp(dope_sltd,'FF = 1.0')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,4);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,4));
                elseif strcmp(dope_sltd,'FF = 1.1')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,5);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,5));
                elseif strcmp(dope_sltd,'FF = 1.1')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,6);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,6));
                end
                ACT = ACT+1;
            elseif ACT == 9 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                if strcmp(app.Connect_process.Value,'Iron Roughneck')
                    pause(15);
                    log_msg = sprintf('%sTongs align and Grip Pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.2;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(8);
                    log_msg = sprintf('%sSpinners screw pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.2;
                    app.Log.Value = log_msg;
                    pause(5);
                elseif strcmp(app.Connect_process.Value,'Power Tongs')
                    pause(10);
                    log_msg = sprintf('%sAlighn Power tongs to pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.4;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(10);
                    log_msg = sprintf('%sProvide make up torque... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.4;
                    app.Log.Value = log_msg;
                elseif strcmp(app.Connect_process.Value,'Pipe Spinners')
                    pause(8);
                    log_msg = sprintf('%sSpinner aligns and screws pipe... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.6;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(8);
                    log_msg = sprintf('%sTongs are aligned and final make up torque is achieved... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.6;
                    app.Log.Value = log_msg;
                elseif strcmp(app.Connect_process.Value,'Spinning Chains and Tongs')
                    pause(5);
                    log_msg = sprintf('%sUse Spinners to screw connection... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.75;
                    app.Log.Value = log_msg;
                    panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                        vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                        vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                        vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                        vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                        vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                        vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                    pause(5);
                    log_msg = sprintf('%sUse tongs for final make up torque... %0.3f sec\n',log_msg,toc);
                    app.riskometer.Value = 0.75;
                    app.Log.Value = log_msg;
                end
                
                if strcmp(dope_sltd,'FF = 0.8')
                    i=1;
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,2);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,2));
                elseif strcmp(dope_sltd,'FF = 0.9')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,3);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,3));
                elseif strcmp(dope_sltd,'FF = 1.0')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,4);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,4));
                elseif strcmp(dope_sltd,'FF = 1.1')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,5);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,5));
                elseif strcmp(dope_sltd,'FF = 1.1')
                    while i<=size(torq,1)
                        app.Gauge.Value = 1000*torq(i,6);
                        i=i+1;
                        pause(0.01);
                    end
                    plot(app.UIAxes,torq(:,1),1000*torq(:,6));
                end
                ACT = ACT+1;
            end
            if ACT == 10 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                app.Gauge.Value = 0;
                log_msg = sprintf('%sSlips out... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            elseif ACT == 10 && strcmp(app.PauseResume.Text,'Resume')
                app.Gauge.Value = 0;
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sSlips out... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                ACT = ACT+1;
            end
            if ACT == 11 && strcmp(app.PauseResume.Text,'Pause')
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sPipe is lowered fo next connection... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
                log_msg = sprintf('%sOperation completed... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
            elseif ACT == 11 && strcmp(app.PauseResume.Text,'Resume')
                log_msg = sprintf('%sSystem Paused at... %0.3f sec\n',log_msg,toc);
                app.Log.Value = log_msg;
                app.Crew_select.Enable = 'on';
                app.Connect_process.Enable = 'on';
                app.Dope_select.Enable = 'on';
                app.PauseResume.Enable = 'off';
                pause(10);
                crew_sltd = app.Crew_select.Value;
                crew_no_sltd = str2double(crew_sltd(6));
                
                for i = 1:6
                    if(crew_no_sltd==i)
                        crew_time = crew(:,i);
                    end
                end
                conn_sltd = app.Connect_process.Value;
                if strcmp(conn_sltd,'Iron Roughneck')
                    crew_time(9,1) = conn(1,crew_no_sltd);
                elseif strcmp(conn_sltd,'Power Tongs')
                    crew_time(9,1) = conn(2,crew_no_sltd);
                elseif strcmp(conn_sltd,'Pipe Spinners')
                    crew_time(9,1) = conn(3,crew_no_sltd);
                elseif strcmp(conn_sltd,'Spinning Chains and Tongs')
                    crew_time(9,1) = conn(4,crew_no_sltd);
                end
                
                crew_acc = crew_time;
                
                for i = 2:11
                    crew_acc(i) = crew_acc(i-1)+crew_time(i);
                end
                dope_sltd = app.Dope_select.Value;
                app.MUTorqueS.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                if strcmp(dope_sltd,'FF = 0.8')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),2))),' Nm');
                elseif strcmp(dope_sltd,'FF = 0.9')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),3))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.0')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),4))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.1')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),5))),' Nm');
                elseif strcmp(dope_sltd,'FF = 1.2')
                    app.MUTorque.Value = strcat(num2str(round(1000*torq(size(torq,1),6))),' Nm');
                end
                app.Crew_select.Enable = 'off';
                app.Connect_process.Enable = 'off';
                app.Dope_select.Enable = 'off';
                app.PauseResume.Enable = 'on';
                app.PauseResume.Text = 'Pause';
                log_msg = sprintf('%sSystem resume at... %0.3f sec\n',log_msg,toc);
                pause(crew_time(ACT,1));
                log_msg = sprintf('%sOperaion complete... %0.3f sec\n',log_msg,toc);
                app.riskometer.Value = safety(ACT);
                app.Log.Value = log_msg;
                panel_status(app,vis(ACT,:),col(ACT,:),vis(ACT+11,:),col(ACT+11,:),...
                    vis(ACT+22,:),col(ACT+22,:),vis(ACT+33,:),col(ACT+33,:),...
                    vis(ACT+44,:),col(ACT+44,:),vis(ACT+55,:),col(ACT+55,:),...
                    vis(ACT+66,:),col(ACT+66,:),vis(ACT+77,:),col(ACT+77,:),...
                    vis(ACT+88,:),col(ACT+88,:),vis(ACT+99,:),col(ACT+99,:),...
                    vis(ACT+110,:),col(ACT+110,:),vis(ACT+121,:),col(ACT+121,:),...
                    vis(ACT+132,:),col(ACT+132,:),vis(ACT+143,:),col(ACT+143,:));
            end
            app.PauseResume.Visible = 'off';
            app.Press_start.Visible = 'on';
            app.Crew_select.Enable = 'on';
            app.Connect_process.Enable = 'on';
            app.Dope_select.Enable = 'on';
            app.MUTorque.Value = '';
            app.MUTorqueS.Value = '';
            app.riskometer.Value = 0;
        end

        % PauseResume button pushed function
        function PauseRes(app)
            if strcmp(app.PauseResume.Text,'Pause')
                app.PauseResume.Text = 'Resume';
            elseif strcmp(app.PauseResume.Text,'Resume')
                app.PauseResume.Text = 'Pause';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Drilling Simulator';
            setAutoResize(app, app.UIFigure, false)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Input Parameters';
            app.Panel.Units = 'pixels';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Crew Selection';
            app.Crew_select = uidropdown(app.Panel);
            app.Crew_select.Items = {'Crew 1', 'Crew 2', 'Crew 3', 'Crew 4', 'Crew 5', 'Crew 6'};
            app.Crew_select.Value = 'Crew 1';
            app.LabelDropDown2 = uilabel(app.Panel);
            app.LabelDropDown2.Text = 'Connection Process';
            app.Connect_process = uidropdown(app.Panel);
            app.Connect_process.Items = {'Iron Roughneck', 'Power Tongs', 'Pipe Spinners', 'Spinning Chains and Tongs'};
            app.Connect_process.Value = 'Iron Roughneck';
            app.LabelDropDown3 = uilabel(app.Panel);
            app.LabelDropDown3.Text = 'Dope Selection';
            app.Dope_select = uidropdown(app.Panel);
            app.Dope_select.Items = {'FF = 0.8', 'FF = 0.9', 'FF = 1.0', 'FF = 1.1', 'FF = 1.2'};
            app.Dope_select.Value = 'FF = 0.8';
            app.Press_start = uibutton(app.Panel, 'state');
            app.Press_start.ValueChangedFcn = createCallbackFcn(app, @Press_Start);
            app.Press_start.Text = 'Start';
            app.PauseResume = uibutton(app.Panel, 'push');
            app.PauseResume.ButtonPushedFcn = createCallbackFcn(app, @PauseRes);
            app.PauseResume.Text = 'Pause';
            app.LabelTextArea = uilabel(app.Panel);
            app.LabelTextArea.Text = 'Make Up Torque (Field)';
            app.MUTorque = uitextarea(app.Panel);
            app.MUTorque.Editable = 'off';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Make Up Torque (Specified)';
            app.MUTorqueS = uitextarea(app.Panel);
            app.MUTorqueS.Editable = 'off';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Simulation';
            app.Panel2.Units = 'pixels';
            app.MUTorque_Specified = uipanel(app.Panel2);
            app.MUTorque_Specified.Units = 'pixels';
            app.Panel4 = uipanel(app.Panel2);
            app.Panel4.Units = 'pixels';
            app.Panel5 = uipanel(app.Panel2);
            app.Panel5.Units = 'pixels';
            app.pipe_box = uipanel(app.Panel2);
            app.pipe_box.Units = 'pixels';
            app.pipe = uipanel(app.Panel2);
            app.pipe.Units = 'pixels';
            app.pipe_pin = uipanel(app.Panel2);
            app.pipe_pin.Units = 'pixels';
            app.Topdrive_top = uipanel(app.Panel2);
            app.Topdrive_top.Title = 'Top Drive';
            app.Topdrive_top.Units = 'pixels';
            app.bottom_pipe = uipanel(app.Panel2);
            app.bottom_pipe.Units = 'pixels';
            app.bottom_pipe_box = uipanel(app.Panel2);
            app.bottom_pipe_box.Units = 'pixels';
            app.Topdrive_bottom = uipanel(app.Panel2);
            app.Topdrive_bottom.Title = 'Top Drive';
            app.Topdrive_bottom.Units = 'pixels';
            app.left_slip = uipanel(app.Panel2);
            app.left_slip.Units = 'pixels';
            app.right_slip = uipanel(app.Panel2);
            app.right_slip.Units = 'pixels';
            app.Panel17 = uipanel(app.Panel2);
            app.Panel17.Units = 'pixels';
            app.Panel18 = uipanel(app.Panel2);
            app.Panel18.Units = 'pixels';
            app.Panel19 = uipanel(app.Panel2);
            app.Panel19.Units = 'pixels';
            app.Panel20 = uipanel(app.Panel2);
            app.Panel20.Units = 'pixels';
            app.Panel21 = uipanel(app.Panel2);
            app.Panel21.Units = 'pixels';
            app.Panel22 = uipanel(app.Panel2);
            app.Panel22.Units = 'pixels';
            app.pipe1_box = uipanel(app.Panel2);
            app.pipe1_box.Units = 'pixels';
            app.pipe1 = uipanel(app.Panel2);
            app.pipe1.Units = 'pixels';
            app.pipe1_pin = uipanel(app.Panel2);
            app.pipe1_pin.Units = 'pixels';
            app.LabelGauge = uilabel(app.Panel2);
            app.LabelGauge.Text = 'Make Up Torque (Nm)';
            app.Gauge = uigauge(app.Panel2, 'circular');
            app.Gauge.Limits = [0 20000];
            app.Gauge.MajorTicks = [0 5000 10000 15000 20000];
            app.tong_right = uipanel(app.Panel2);
            app.tong_right.Title = 'Lead Tong';
            app.tong_right.Units = 'pixels';
            app.tong_left = uipanel(app.Panel2);
            app.tong_left.Title = 'Backup Tong';
            app.tong_left.Units = 'pixels';
            app.LabelLinearGauge = uilabel(app.Panel2);
            app.LabelLinearGauge.Text = 'Risk-meter';
            app.riskometer = uigauge(app.Panel2, 'linear');
            app.riskometer.Limits = [0 1];
            app.Label = uilabel(app.Panel2);
            app.Label.Text = 'Derrick Floor';
            app.MUGauge = uigauge(app.Panel2, 'linear');
            app.MUGauge.Limits = [0 1];
            app.MUGauge.MajorTicks = [0 0.5 1];
            app.MUGauge.MinorTicks = [];
            app.MUGauge.Enable = 'off';
            app.Panel26 = uipanel(app.UIFigure);
            app.Panel26.Title = 'Output Display';
            app.Panel26.Units = 'pixels';
            app.UIAxes = uiaxes(app.Panel26);
            title(app.UIAxes, 'Torque-Turn Plot');
            xlabel(app.UIAxes, 'Turns (in degrees)');
            ylabel(app.UIAxes, 'Torque (in Nm)');
            app.Log = uitextarea(app.Panel26);
            app.Log.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pipe_Make_up_Simulator_Project()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end