classdef check < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure              % UI Figure
        Button      matlab.ui.control.Button      % Button
        StateButton matlab.ui.control.StateButton % State Button
        TextArea    matlab.ui.control.TextArea   
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function button(app)
            tic;
            pause(2);
            app.TextArea.Value = num2str(toc);
            if(app.StateButton.Value == 1)
                pause(2);
            else pause (4);
            end
            app.TextArea.Value = num2str(toc);
            if(app.StateButton.Value == 1)
                pause(2);
            else pause (4);
            end
            app.TextArea.Value = num2str(toc);
            if(app.StateButton.Value == 1)
                pause(2);
            else pause (4);
            end
            app.TextArea.Value = num2str(toc);
            if(app.StateButton.Value == 1)
                pause(2);
            else pause (4);
            end
            app.TextArea.Value = num2str(toc);
            app.TextArea.Value = num2str(toc);
            if(app.StateButton.Value == 1)
                pause(2);
            else pause (4);
            end
            app.TextArea.Value = num2str(toc);
            app.TextArea.Value = num2str(toc);
            if(app.StateButton.Value == 1)
                pause(2);
            else pause (4);
            end
            app.TextArea.Value = num2str(toc);
            app.TextArea.Value = num2str(toc);
            if(app.StateButton.Value == 1)
                pause(2);
            else pause (4);
            end
            app.TextArea.Value = num2str(toc);
            
            
        end

        % StateButton value changed function
        function statebutton(app)
%             tic
%             pause(2);
%             app.TextArea.Value = num2str(toc);
%             app.StateButton.Value = 1;
%             pause(2);
%             app.TextArea.Value = num2str(toc);
%             app.StateButton.Value = 0;
%             pause(2);
%             app.TextArea.Value = num2str(toc);
%             app.StateButton.Value = 1;
%             pause(2);
%             app.TextArea.Value = num2str(toc);
%             app.StateButton.Value = 0;
%             pause(2);
%             app.TextArea.Value = num2str(toc);
%             app.StateButton.Value = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @button);
            app.StateButton = uibutton(app.UIFigure, 'state');
            app.StateButton.ValueChangedFcn = createCallbackFcn(app, @statebutton);
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = check()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end